classdef HSVSpaceImgSep < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        UIAxes                   matlab.ui.control.UIAxes
        ReadButton               matlab.ui.control.Button
        SaveButton               matlab.ui.control.Button
        Lamp                     matlab.ui.control.Lamp
        ImageNameEditFieldLabel  matlab.ui.control.Label
        ImageNameEditField       matlab.ui.control.EditField
        VMinSliderLabel          matlab.ui.control.Label
        VMinSlider               matlab.ui.control.Slider
        VMaxSliderLabel          matlab.ui.control.Label
        VMaxSlider               matlab.ui.control.Slider
        Switch                   matlab.ui.control.Switch
        SMinSliderLabel          matlab.ui.control.Label
        SMinSlider               matlab.ui.control.Slider
        SMaxSliderLabel          matlab.ui.control.Label
        SMaxSlider               matlab.ui.control.Slider
        HMinSliderLabel          matlab.ui.control.Label
        HMinSlider               matlab.ui.control.Slider
        HMaxSliderLabel          matlab.ui.control.Label
        HMaxSlider               matlab.ui.control.Slider
        UIAxes2                  matlab.ui.control.UIAxes
    end


    methods (Access = private)
    
        function results = ReadThreshold(app)
            global H_R_Min_value
            H_R_Min_value = app.HMinSlider.Value;
            global H_R_Max_value
            H_R_Max_value = app.HMaxSlider.Value;
            global S_G_Min_value
            S_G_Min_value = app.SMinSlider.Value;
            global S_G_Max_value
            S_G_Max_value = app.SMaxSlider.Value;
            global V_B_Min_value
            V_B_Min_value = app.VMinSlider.Value;
            global V_B_Max_value
            V_B_Max_value = app.VMaxSlider.Value;
        end
    
        
        function results = ExtractorDispImg(app)
            global ImageName ColorSpace ImgExtractor
            global H_R_Min_value H_R_Max_value
            global S_G_Min_value S_G_Max_value
            global V_B_Min_value V_B_Max_value

            ReadThreshold(app);
            ColorSpace = app.Switch.Value;
                        
            Img = imread(ImageName);
            if ColorSpace == "HSV"
                Img = rgb2hsv(Img);
                % 创建空白的图像
                ImgNew = 255*ones(size(Img));
                ImgNew = rgb2hsv(ImgNew);
                [row, col] = ind2sub( size(Img),...
                    find( Img(:,:,1)>=H_R_Min_value & Img(:,:,1)<= H_R_Max_value ...
                        & Img(:,:,2)>=S_G_Min_value & Img(:,:,2)<= S_G_Max_value ...
                        & Img(:,:,3)>=V_B_Min_value & Img(:,:,3)<= V_B_Max_value ) );
                
                % 将图像中的黄色像素复制到刚才新建的白色图像中
                for i = 1 : length(row)
                    ImgNew(row(i),col(i),:) = Img(row(i),col(i),:);
                end
                
                ImgExtractor = hsv2rgb(ImgNew);     % 将提取出来的黄色，转化至rgb空间，进行展示
                
                imshow(ImgExtractor,'Parent',app.UIAxes);
            end
            
            if ColorSpace == "RGB"
                % 创建空白的图像
                ImgNew = 0*ones(size(Img));
                [row, col] = ind2sub( size(Img),...
                    find( Img(:,:,1)>=H_R_Min_value & Img(:,:,1)<= H_R_Max_value ...
                        & Img(:,:,2)>=S_G_Min_value & Img(:,:,2)<= S_G_Max_value ...
                        & Img(:,:,3)>=V_B_Min_value & Img(:,:,3)<= V_B_Max_value ) );
                
                % 将图像中的黄色像素复制到刚才新建的白色图像中
                for i = 1 : length(row)
                    ImgNew(row(i),col(i),:) = Img(row(i),col(i),:);
                end
                
                ImgExtractor = ImgNew;     % 将提取出来的黄色，转化至rgb空间，进行展示
                
                imshow(ImgExtractor,'Parent',app.UIAxes);
            end


        end
        
    end


    methods (Access = private)

        % Button pushed function: ReadButton
        function ReadButtonPushed(app, event)
            global ImageName ColorSpace
            
            ColorSpace = app.Switch.Value;
            ImageName = app.ImageNameEditField.Value;
            
            try
                Img = imread(ImageName);
                app.Lamp.Color = 'green';
                imshow(Img,'Parent',app.UIAxes);
                imshow(Img,'Parent',app.UIAxes2);
            catch
                app.ImageNameEditField.Value = 'Image Name Error';
                app.Lamp.Color = 'red';
            end
        end

        % Value changed function: Switch
        function SwitchValueChanged(app, event)
            value = app.Switch.Value;
            if value == "RGB"
                app.HMinSliderLabel.Text = 'R-Min';
                app.HMaxSliderLabel.Text = 'R-Max';
                app.SMinSliderLabel.Text = 'G-Min';
                app.SMaxSliderLabel.Text = 'G-Max';
                app.VMinSliderLabel.Text = 'B-Min';
                app.VMaxSliderLabel.Text = 'B-Max';
            else
                app.HMinSliderLabel.Text = 'H-Min';
                app.HMaxSliderLabel.Text = 'H-Max';
                app.SMinSliderLabel.Text = 'S-Min';
                app.SMaxSliderLabel.Text = 'S-Max';
                app.VMinSliderLabel.Text = 'V-Min';
                app.VMaxSliderLabel.Text = 'V-Max';
            end
        end

        % Value changed function: HMinSlider
        function HMinSliderValueChanged(app, event)
            global H_R_Min_value
            ReadThreshold(app);
            ExtractorDispImg(app);
            app.HMinSliderLabel.Text = num2str(H_R_Min_value);
        end

        % Value changed function: HMaxSlider
        function HMaxSliderValueChanged(app, event)
            global H_R_Max_value
            ReadThreshold(app);
            ExtractorDispImg(app);
            app.HMaxSliderLabel.Text = num2str(H_R_Max_value);
        end

        % Value changed function: SMinSlider
        function SMinSliderValueChanged(app, event)
            global S_G_Min_value
            ReadThreshold(app);
            ExtractorDispImg(app);
            app.SMinSliderLabel.Text = num2str(S_G_Min_value);
        end

        % Value changed function: SMaxSlider
        function SMaxSliderValueChanged(app, event)
            global S_G_Max_value
            ReadThreshold(app);
            ExtractorDispImg(app);
            app.SMaxSliderLabel.Text = num2str(S_G_Max_value);
        end

        % Value changed function: VMinSlider
        function VMinSliderValueChanged(app, event)
            global V_B_Min_value
            ReadThreshold(app);
            ExtractorDispImg(app);
            app.VMinSliderLabel.Text = num2str(V_B_Min_value);
        end

        % Value changed function: VMaxSlider
        function VMaxSliderValueChanged(app, event)
            global V_B_Max_value
            ReadThreshold(app);
            ExtractorDispImg(app);
            app.VMaxSliderLabel.Text = num2str(V_B_Max_value);
        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
            global ImgExtractor ImageName
            imwrite(ImgExtractor, strcat(ImageName, 'ImgExtractor.png'))
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.ReadButton = uibutton(app.UIFigure, 'push');
            app.ReadButton.ButtonPushedFcn = createCallbackFcn(app, @ReadButtonPushed, true);
            app.ReadButton.Text = 'Read';
            app.SaveButton = uibutton(app.UIFigure, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Text = 'Save';
            app.Lamp = uilamp(app.UIFigure);
            app.ImageNameEditFieldLabel = uilabel(app.UIFigure);
            app.ImageNameEditFieldLabel.Text = 'ImageName';
            app.ImageNameEditField = uieditfield(app.UIFigure, 'text');
            app.ImageNameEditField.Value = 'HSV.png';
            app.VMinSliderLabel = uilabel(app.UIFigure);
            app.VMinSliderLabel.Text = 'V-Min';
            app.VMinSlider = uislider(app.UIFigure);
            app.VMinSlider.Limits = [0 1];
            app.VMinSlider.MajorTicks = [];
            app.VMinSlider.MajorTickLabels = {};
            app.VMinSlider.ValueChangedFcn = createCallbackFcn(app, @VMinSliderValueChanged, true);
            app.VMinSlider.MinorTicks = [];
            app.VMaxSliderLabel = uilabel(app.UIFigure);
            app.VMaxSliderLabel.Text = 'V-Max';
            app.VMaxSlider = uislider(app.UIFigure);
            app.VMaxSlider.Limits = [0 1];
            app.VMaxSlider.MajorTicks = [];
            app.VMaxSlider.MajorTickLabels = {};
            app.VMaxSlider.ValueChangedFcn = createCallbackFcn(app, @VMaxSliderValueChanged, true);
            app.VMaxSlider.MinorTicks = [];
            app.VMaxSlider.Value = 1;
            app.Switch = uiswitch(app.UIFigure, 'slider');
            app.Switch.Items = {'HSV', 'RGB'};
            app.Switch.ValueChangedFcn = createCallbackFcn(app, @SwitchValueChanged, true);
            app.Switch.Value = 'HSV';
            app.SMinSliderLabel = uilabel(app.UIFigure);
            app.SMinSliderLabel.Text = 'S-Min';
            app.SMinSlider = uislider(app.UIFigure);
            app.SMinSlider.Limits = [0 1];
            app.SMinSlider.MajorTicks = [];
            app.SMinSlider.MajorTickLabels = {};
            app.SMinSlider.ValueChangedFcn = createCallbackFcn(app, @SMinSliderValueChanged, true);
            app.SMinSlider.MinorTicks = [];
            app.SMaxSliderLabel = uilabel(app.UIFigure);
            app.SMaxSliderLabel.Text = 'S-Max';
            app.SMaxSlider = uislider(app.UIFigure);
            app.SMaxSlider.Limits = [0 1];
            app.SMaxSlider.MajorTicks = [];
            app.SMaxSlider.MajorTickLabels = {};
            app.SMaxSlider.ValueChangedFcn = createCallbackFcn(app, @SMaxSliderValueChanged, true);
            app.SMaxSlider.MinorTicks = [];
            app.SMaxSlider.Value = 1;
            app.HMinSliderLabel = uilabel(app.UIFigure);
            app.HMinSliderLabel.Text = 'H-Min';
            app.HMinSlider = uislider(app.UIFigure);
            app.HMinSlider.Limits = [0 1];
            app.HMinSlider.MajorTicks = [];
            app.HMinSlider.MajorTickLabels = {};
            app.HMinSlider.ValueChangedFcn = createCallbackFcn(app, @HMinSliderValueChanged, true);
            app.HMinSlider.MinorTicks = [];
            app.HMaxSliderLabel = uilabel(app.UIFigure);
            app.HMaxSliderLabel.Text = 'H-Max';
            app.HMaxSlider = uislider(app.UIFigure);
            app.HMaxSlider.Limits = [0 1];
            app.HMaxSlider.MajorTicks = [];
            app.HMaxSlider.MajorTickLabels = {};
            app.HMaxSlider.ValueChangedFcn = createCallbackFcn(app, @HMaxSliderValueChanged, true);
            app.HMaxSlider.MinorTicks = [];
            app.HMaxSlider.Value = 1;
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, 'Original Image')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
        end
    end

    methods (Access = public)

        % Construct app
        function app = HSVSpaceImgSep

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end